classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        NumericalAnalysisLabel          matlab.ui.control.Label
        BrowseButton                    matlab.ui.control.Button
        MethodDropDownLabel             matlab.ui.control.Label
        MethodDropDown                  matlab.ui.control.DropDown
        IterationsEditFieldLabel        matlab.ui.control.Label
        IterationsEditField             matlab.ui.control.NumericEditField
        UITable                         matlab.ui.control.Table
        EpsilonEditFieldLabel           matlab.ui.control.Label
        EpsilonEditField                matlab.ui.control.NumericEditField
        Label                           matlab.ui.control.Label
        GoButton                        matlab.ui.control.Button
        DirectoryEditFieldLabel         matlab.ui.control.Label
        DirectoryEditField              matlab.ui.control.EditField
        NumberofEquationsdetectedLabel  matlab.ui.control.Label
        NextButton                      matlab.ui.control.Button
        ZeroLabel                       matlab.ui.control.Label
        EquationsEditFieldLabel         matlab.ui.control.Label
        EquationsEditField              matlab.ui.control.EditField
        BackButton                      matlab.ui.control.Button
        IntialguessesEditFieldLabel     matlab.ui.control.Label
        IntialguessesEditField          matlab.ui.control.EditField
        AnswerEditFieldLabel            matlab.ui.control.Label
        AnswerEditField                 matlab.ui.control.EditField
        PlotButton                      matlab.ui.control.Button
        UIAxes                          matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        cursor_location = [1]
        no_of_equ
        equations_used ;
        method
        fileID
        problem_no = 1;
        plots = []
        index = 0
        plotrows % Description
    end
    
    methods (Access = private)
        
        function presentEquation(app)
            fseek(app.fileID,app.cursor_location(app.problem_no),"bof");
            app.no_of_equ = str2double(fgetl(app.fileID));
            if (isnan(app.no_of_equ))
                app.problem_no = length(app.cursor_location) - 1;
                return
            end
            app.method = fgetl(app.fileID);
            app.MethodDropDown.Value = app.method;
            equations = [];
            app.equations_used = [];
            app.ZeroLabel.Text = num2str(app.no_of_equ);
            for i=1:app.no_of_equ
                equ = fgetl(app.fileID);
                app.equations_used = [app.equations_used str2sym(string(equ))];
                if(i ~= app.no_of_equ)
                    equ = strcat(equ,",");
                end
                equations = strcat(equations,equ);
                
            end
           
            app.EquationsEditField.Value = equations;
            fgetl(app.fileID);
            located = any(app.cursor_location(:) == ftell(app.fileID));
            if (located == false)
                app.cursor_location = [app.cursor_location ftell(app.fileID)];
            end
            
        end
        
        function results = gauss_elimnation(app)
            syms a b c d e f g h x y z m
            [Matrix,SourceMatrix] = equationsToMatrix(app.equations_used);
            N = length(SourceMatrix);
            X = zeros(N,1);
            AugmentedMatrix = [Matrix SourceMatrix];
           
            for j=1:N-1
                for i=j+1:N
                    m = AugmentedMatrix(i,j)/AugmentedMatrix(j,j);
                    AugmentedMatrix(i,:)= AugmentedMatrix(i,:) - m*AugmentedMatrix(j,:);
                end
            end
            X(N) = AugmentedMatrix(N,N+1)/AugmentedMatrix(N,N);
            for k=N-1:-1:1
                X(k) = (AugmentedMatrix(k,N+1) - AugmentedMatrix(k,k+1:N)*X(k+1:N))/AugmentedMatrix(k,k);
            end
            X = string(X');
          
            results = [];
            for u=1:length(X)
                results = strcat(results,X(u));
                results = strcat(results," ");
            end
            if (ismissing(results))
                app.AnswerEditField.Value = "Error";
            else
                 app.AnswerEditField.Value = results;
            end
           
        end
        
        function results=gauss_seidel(app,guesses,iterations,epislon)
            syms a b c d e f g h x y z m
            vars = [a b c d e f g h x y z m];
            cols = {'a' 'err' 'b' 'err' 'c' 'err' 'd' 'err' 'e' 'err' 'f' 'err' 'g' 'err' 'h' 'err'};
            [Matrix,SoucreMatrix] = equationsToMatrix(app.equations_used);
            N = length(SoucreMatrix);
            app.UITable.Data = [];
            app.UITable.ColumnName = {'iterations' cols{1:2*N}};
             app.plots = [];
            X = zeros(N,1);
            Y = zeros(N,1);
            for j=1:iterations
                for i=1:N
                    X(i) = SoucreMatrix(i)/Matrix(i,i) - (Matrix(i,[1:i-1,i+1:N])*guesses([1:i-1,i+1:N]))/Matrix(i,i);
                    guesses(i) = X(i);
                end
                error = abs(X-Y)./X;
                row = [j];
                for u=1:N
                    row = [row X(u) error(u)];
                end
               
                app.UITable.Data = [app.UITable.Data; row];
                app.plots = [app.plots; X'];
                if (error < epislon)
                    break;
                end
                Y=X;
            end
            X = string(X');
            dou = str2double(X);
            results =[];
            val = 0;
            for r=1:N
                val=Matrix(1,r)*dou(r) + val;
            end
            disp(floor(val));
            disp(SoucreMatrix(1,1));
            if(floor(val) == SoucreMatrix(1,1))
            for u=1:length(X)
                results = strcat(results,X(u));
                results = strcat(results," ");
            end
               app.plots = app.plots';
               WL = size(app.plots);
               app.plotrows = WL(1);
               app.AnswerEditField.Value = results;
            else
                app.AnswerEditField.Value = 'Error';
            end

        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            [filename,filepath] = uigetfile({'.txt'},'Search For the text file containing equations');
            fullname = [filepath filename];
            if ischar(fullname)
                if  isfile(fullname)
                    app.DirectoryEditField.Value = fullname;
                    app.fileID = fopen(fullname,'r');
                    app.cursor_location = fseek(app.fileID,0,"bof");
                    presentEquation(app);
                end
            end
        end

        % Button pushed function: NextButton
        function NextButtonPushed(app, event)
               app.problem_no = app.problem_no + 1;
               presentEquation(app);
        end

        % Button pushed function: BackButton
        function BackButtonPushed(app, event)
                if(app.problem_no - 1 < 1)
                    app.problem_no = 1;
                    presentEquation(app)
                else
                    app.problem_no = app.problem_no -1;
                    presentEquation(app);
                end
        end

        % Button pushed function: GoButton
        function GoButtonPushed(app, event)
            app.index = 0;
            app.UIAxes.cla;
            if (strcmp(app.method,"Gaussian-elimination")) 
               gauss_elimnation(app);
           elseif (strcmp(app.method,"Gaussian-seidel"))
              iterations = app.IterationsEditField.Value;
              epislon = app.EpsilonEditField.Value;
              
              guesses = app.IntialguessesEditField.Value;
              guesses =  str2double(split(guesses," "));
              if (isnan(guesses))
                  app.AnswerEditField.Value = 'ERROR no intial Guesses';
                  return
              end
              gauss_seidel(app,guesses,iterations,epislon);
           end
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            app.index = app.index + 1;
            
            y = zeros(1,length(app.plots));
            if(app.index < length(app.plots))
                for t=1:app.plotrows
                    disp(app.plots(t,1:app.index));
                    disp(y(1:app.index));
                    scatter(app.UIAxes,app.plots(t,1:app.index),y(1:app.index));
                    hold(app.UIAxes,'on');
                end
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.NumericalAnalysisLabel = uilabel(app.UIFigure);
            app.NumericalAnalysisLabel.Text = 'Numerical Analysis';
            app.BrowseButton = uibutton(app.UIFigure, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse';
            app.MethodDropDownLabel = uilabel(app.UIFigure);
            app.MethodDropDownLabel.Text = 'Method';
            app.MethodDropDown = uidropdown(app.UIFigure);
            app.MethodDropDown.Items = {'Gaussian-elimination', 'Gaussian-seidel'};
            app.MethodDropDown.Value = 'Gaussian-elimination';
            app.IterationsEditFieldLabel = uilabel(app.UIFigure);
            app.IterationsEditFieldLabel.Text = 'Iterations';
            app.IterationsEditField = uieditfield(app.UIFigure, 'numeric');
            app.IterationsEditField.Value = 50;
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.EpsilonEditFieldLabel = uilabel(app.UIFigure);
            app.EpsilonEditFieldLabel.Text = 'Epsilon';
            app.EpsilonEditField = uieditfield(app.UIFigure, 'numeric');
            app.EpsilonEditField.Value = 1e-05;
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '____________________________________________________________________________________________';
            app.GoButton = uibutton(app.UIFigure, 'push');
            app.GoButton.ButtonPushedFcn = createCallbackFcn(app, @GoButtonPushed, true);
            app.GoButton.Text = 'Go';
            app.DirectoryEditFieldLabel = uilabel(app.UIFigure);
            app.DirectoryEditFieldLabel.Text = 'Directory';
            app.DirectoryEditField = uieditfield(app.UIFigure, 'text');
            app.NumberofEquationsdetectedLabel = uilabel(app.UIFigure);
            app.NumberofEquationsdetectedLabel.Text = 'Number of Equations detected:';
            app.NextButton = uibutton(app.UIFigure, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPushed, true);
            app.NextButton.Text = 'Next';
            app.ZeroLabel = uilabel(app.UIFigure);
            app.ZeroLabel.Text = 'Zero';
            app.EquationsEditFieldLabel = uilabel(app.UIFigure);
            app.EquationsEditFieldLabel.Text = 'Equations';
            app.EquationsEditField = uieditfield(app.UIFigure, 'text');
            app.BackButton = uibutton(app.UIFigure, 'push');
            app.BackButton.ButtonPushedFcn = createCallbackFcn(app, @BackButtonPushed, true);
            app.BackButton.Text = 'Back';
            app.IntialguessesEditFieldLabel = uilabel(app.UIFigure);
            app.IntialguessesEditFieldLabel.Text = 'Intial guesses';
            app.IntialguessesEditField = uieditfield(app.UIFigure, 'text');
            app.AnswerEditFieldLabel = uilabel(app.UIFigure);
            app.AnswerEditFieldLabel.Text = 'Answer';
            app.AnswerEditField = uieditfield(app.UIFigure, 'text');
            app.PlotButton = uibutton(app.UIFigure, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Roots Plots')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end